classdef SerialCOM < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        AngledegEditField       matlab.ui.control.NumericEditField
        AngledegEditFieldLabel  matlab.ui.control.Label
        StartButton             matlab.ui.control.Button
        UIAxes                  matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            s = serialport('COM7', 115200);
            configureTerminator(s, "CR/LF");
            % app.h = animatedline(app.UIAxis, app.TIME(y,1),app.VAR1(y,1));
            h = animatedline(app.UIAxes,'Color','b','LineWidth',2, 'MaximumNumPoints',2);
            while 1
                data = str2double(strsplit(readline(s), ','));
                app.AngledegEditField.Value = data(2);
                x = cosd(data(2)); y = sind(data(2)); 
                addpoints(h,[0, x],[0, y]);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XLim = [-1 1];
            app.UIAxes.YLim = [-1 1];
            app.UIAxes.XTick = [-1 0 1];
            app.UIAxes.XTickLabel = {'-1'; '0'; '1'};
            app.UIAxes.YTick = [-1 0 1];
            app.UIAxes.YTickLabel = {'-1'; '0'; '1'};
            app.UIAxes.ZTick = [0 0.5 1];
            app.UIAxes.ZTickLabel = '';
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.AngledegEditFieldLabel = uilabel(app.UIFigure);
            app.AngledegEditFieldLabel.Text = 'Angle (deg)';
            app.AngledegEditField = uieditfield(app.UIFigure, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SerialCOM

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end